classdef DistillationColumnApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        Component1EditFieldLabel      matlab.ui.control.Label
        Component1EditField           matlab.ui.control.NumericEditField
        Component2EditFieldLabel      matlab.ui.control.Label
        Component2EditField           matlab.ui.control.NumericEditField
        FeedCompositionsLabel         matlab.ui.control.Label
        FeedFlowInEditFieldLabel      matlab.ui.control.Label
        FeedFlowInEditField           matlab.ui.control.NumericEditField
        FeedTempCEditFieldLabel       matlab.ui.control.Label
        FeedTempCEditField            matlab.ui.control.NumericEditField
        ColumnPressuremmHgEditFieldLabel  matlab.ui.control.Label
        ColumnPressuremmHgEditField   matlab.ui.control.NumericEditField
        RefluxRatioEditFieldLabel     matlab.ui.control.Label
        RefluxRatioEditField          matlab.ui.control.NumericEditField
        xDcomp1EditFieldLabel         matlab.ui.control.Label
        xDcomp1EditField              matlab.ui.control.NumericEditField
        xWcomp1EditFieldLabel         matlab.ui.control.Label
        xWcomp1EditField              matlab.ui.control.NumericEditField
        Comp1AValEditFieldLabel       matlab.ui.control.Label
        Comp1AValEditField            matlab.ui.control.NumericEditField
        Comp1BValEditFieldLabel       matlab.ui.control.Label
        Comp1BValEditField            matlab.ui.control.NumericEditField
        Comp1CValEditFieldLabel       matlab.ui.control.Label
        Comp1CValEditField            matlab.ui.control.NumericEditField
        AntoineEquationValuesforbothComponentsLabel  matlab.ui.control.Label
        Comp2AValEditFieldLabel       matlab.ui.control.Label
        Comp2AValEditField            matlab.ui.control.NumericEditField
        Comp2BValEditField_2Label     matlab.ui.control.Label
        Comp2BValEditField_2          matlab.ui.control.NumericEditField
        Comp2CValEditField_2Label     matlab.ui.control.Label
        Comp2CValEditField_2          matlab.ui.control.NumericEditField
        CalculateButton               matlab.ui.control.Button
        ColumnBubbleTempLabel         matlab.ui.control.Label
        ColumnDewTempLabel            matlab.ui.control.Label
        FeedStateLabel                matlab.ui.control.Label
        DistillateFlowRateLabel       matlab.ui.control.Label
        BottomsFlowRateLabel          matlab.ui.control.Label
        DoyouwanttoincludethevaporandliquidstreamsLabel  matlab.ui.control.Label
        abovethefeedtrayLabel         matlab.ui.control.Label
        SwitchLabel                   matlab.ui.control.Label
        Switch                        matlab.ui.control.ToggleSwitch
        VaporFlowRateAboveFeedLabel   matlab.ui.control.Label
        LiquidFlowRateAboveFeedLabel  matlab.ui.control.Label
        AssumesMcCabeThieleconditionsandLabel  matlab.ui.control.Label
        idealconditionsLabel          matlab.ui.control.Label
        GeneralColumnCharacteristicsLabel  matlab.ui.control.Label
        ColumnBubbleTLabel            matlab.ui.control.Label
        ColumnDewTLabel               matlab.ui.control.Label
        DFlowLabel                    matlab.ui.control.Label
        WFlowLabel                    matlab.ui.control.Label
        VAboveLabel                   matlab.ui.control.Label
        LAboveLabel2                  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % Making the variables
            z1 = app.Component1EditField.Value;
            z2 = app.Component2EditField.Value;
            xD = app.xDcomp1EditField.Value;
            xW = app.xWcomp1EditField.Value;
            F = app.FeedFlowInEditField.Value;
            Tf = app.FeedTempCEditField.Value;
            cPressure = app.ColumnPressuremmHgEditField.Value;
            R = app.RefluxRatioEditField.Value;
            A1 = app.Comp1AValEditField.Value;
            B1 = app.Comp1BValEditField.Value;
            C1 = app.Comp1CValEditField.Value;
            A2 = app.Comp2AValEditField.Value;
            B2 = app.Comp2BValEditField_2.Value;
            C2 = app.Comp2CValEditField_2.Value;
            
            SCL = imread('MATLABSubcooledLiquid.PNG');
            SL = imread('MATLABSaturatedLiquid.PNG');
            twoPhase = imread('MATLAB2Phase.PNG');
            SV = imread('MATLABSaturatedVapor.PNG');
            SHV = imread('MATLABSuperheatedVapor.PNG');
            
            % Finding the bubble and dew temperatures
            bubbleDif = 1000;
            bubbleT = 0;
            for T = 1:0.5:1000
                p1 = exp(A1 - (B1 / (C1 + T)));
                p2 = exp(A2 - (B2 / (C2 + T)));
                totalP = (z1 * p1) + (z2 * p2);
                trueDif = totalP - cPressure;
                if abs(trueDif) < bubbleDif
                    bubbleDif = abs(trueDif);
                    bubbleT = T;
                end 
            end
            
            Tb = bubbleT;
            app.ColumnBubbleTempLabel.Text = sprintf('%.2f', Tb);
            
            dewDif = 1000;
            dewT = 0;
            for M = 1:0.5:1000
                p1 = exp(A1 - (B1 / (C1 + M)));
                p2 = exp(A2 - (B2 / (C2 + M)));
                totalDewP = 1 / ((z1 / p1) + (z2 / p2));
                trueDewDif = abs(totalDewP - cPressure);
                if abs(trueDewDif) < dewDif
                    dewDif = abs(trueDewDif);
                    dewT = M;
                end
            end
            
            Td = dewT;
            app.ColumnDewTempLabel.Text = sprintf('%.2f', Td);
            
            % Classifying the feed
            if Tf > Td
                app.FeedStateLabel.Text = "Feed is superheated vapor!";
                imshow(SHV, 'Parent', app.UIAxes);
            else if Tf == Td
                    app.FeedStateLabel.Text = "Feed is a saturated vapor!";
                    imshow(SV, 'Parent', app.UIAxes)
            else if (Tf > Tb) && (Tf < Td)
                    app.FeedStateLabel.Text = "Feed is 2 phase";
                    imshow(twoPhase, 'Parent', app.UIAxes)
            else if Tf == Tb 
                    app.FeedStateLabel.Text = "Feed is a saturated liquid!";
                    imshow(SL, 'Parent', app.UIAxes)
            else if Tf < Tb 
                    app.FeedStateLabel.Text = "Feed is subcooled liquid!";
                    imshow(SCL, 'Parent', app.UIAxes)
            end
            end
            end
            end
            end
            
            % Calculating the distillate and bottom flow rates
            W = (F * (z1 - xD)) / (xW - xD);
            D = F - W;
            
            app.DFlowLabel.Text = sprintf('%0.2f', D);
            app.WFlowLabel.Text = sprintf('%0.2f', W);    
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            z1 = app.Component1EditField.Value;
            z2 = app.Component2EditField.Value;
            xD = app.xDcomp1EditField.Value;
            xW = app.xWcomp1EditField.Value;
            F = app.FeedFlowInEditField.Value;
            R = app.RefluxRatioEditField.Value;
            
            % Calculating the distillate and bottom flow rates
            W = (F * (z1 - xD)) / (xW - xD);
            D = F - W;
            
            % Switch Portion: V/L streams above feed
            Lo = D * R;
            V1 = D + Lo;
            
            % If statement for the switch 
            value = app.Switch.Value;
            
            if strcmp(value, 'On')
                % Switch Portion: V/L streams above feed
                app.VAboveLabel.Text = sprintf('%0.2f', V1);
                app.LAboveLabel2.Text = sprintf('%0.2f', Lo);
            else 
                app.VAboveLabel.Text = "Value toggled off";
                app.LAboveLabel2.Text = "Value toggled off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Component1EditFieldLabel = uilabel(app.Panel);
            app.Component1EditFieldLabel.Text = 'Component 1 ';
            app.Component1EditField = uieditfield(app.Panel, 'numeric');
            app.Component2EditFieldLabel = uilabel(app.Panel);
            app.Component2EditFieldLabel.Text = 'Component 2';
            app.Component2EditField = uieditfield(app.Panel, 'numeric');
            app.FeedCompositionsLabel = uilabel(app.Panel);
            app.FeedCompositionsLabel.Text = 'Feed Compositions';
            app.FeedFlowInEditFieldLabel = uilabel(app.Panel);
            app.FeedFlowInEditFieldLabel.Text = 'Feed Flow In';
            app.FeedFlowInEditField = uieditfield(app.Panel, 'numeric');
            app.FeedTempCEditFieldLabel = uilabel(app.Panel);
            app.FeedTempCEditFieldLabel.Text = 'Feed Temp (C)';
            app.FeedTempCEditField = uieditfield(app.Panel, 'numeric');
            app.ColumnPressuremmHgEditFieldLabel = uilabel(app.Panel);
            app.ColumnPressuremmHgEditFieldLabel.Text = 'Column Pressure (mmHg)';
            app.ColumnPressuremmHgEditField = uieditfield(app.Panel, 'numeric');
            app.RefluxRatioEditFieldLabel = uilabel(app.Panel);
            app.RefluxRatioEditFieldLabel.Text = 'Reflux Ratio';
            app.RefluxRatioEditField = uieditfield(app.Panel, 'numeric');
            app.xDcomp1EditFieldLabel = uilabel(app.Panel);
            app.xDcomp1EditFieldLabel.Text = 'xD (comp. 1)';
            app.xDcomp1EditField = uieditfield(app.Panel, 'numeric');
            app.xWcomp1EditFieldLabel = uilabel(app.Panel);
            app.xWcomp1EditFieldLabel.Text = 'xW (comp. 1)';
            app.xWcomp1EditField = uieditfield(app.Panel, 'numeric');
            app.Comp1AValEditFieldLabel = uilabel(app.Panel);
            app.Comp1AValEditFieldLabel.Text = 'Comp. 1 A Val';
            app.Comp1AValEditField = uieditfield(app.Panel, 'numeric');
            app.Comp1BValEditFieldLabel = uilabel(app.Panel);
            app.Comp1BValEditFieldLabel.Text = 'Comp. 1 B Val';
            app.Comp1BValEditField = uieditfield(app.Panel, 'numeric');
            app.Comp1CValEditFieldLabel = uilabel(app.Panel);
            app.Comp1CValEditFieldLabel.Text = 'Comp. 1 C Val';
            app.Comp1CValEditField = uieditfield(app.Panel, 'numeric');
            app.AntoineEquationValuesforbothComponentsLabel = uilabel(app.Panel);
            app.AntoineEquationValuesforbothComponentsLabel.Text = 'Antoine Equation Values for both Components:';
            app.Comp2AValEditFieldLabel = uilabel(app.Panel);
            app.Comp2AValEditFieldLabel.Text = 'Comp. 2 A Val';
            app.Comp2AValEditField = uieditfield(app.Panel, 'numeric');
            app.Comp2BValEditField_2Label = uilabel(app.Panel);
            app.Comp2BValEditField_2Label.Text = 'Comp. 2 B Val';
            app.Comp2BValEditField_2 = uieditfield(app.Panel, 'numeric');
            app.Comp2CValEditField_2Label = uilabel(app.Panel);
            app.Comp2CValEditField_2Label.Text = 'Comp. 2 C Val';
            app.Comp2CValEditField_2 = uieditfield(app.Panel, 'numeric');
            app.CalculateButton = uibutton(app.Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ColumnBubbleTempLabel = uilabel(app.Panel);
            app.ColumnBubbleTempLabel.Text = '0';
            app.ColumnDewTempLabel = uilabel(app.Panel);
            app.ColumnDewTempLabel.Text = '0';
            app.FeedStateLabel = uilabel(app.Panel);
            app.FeedStateLabel.Text = {'0'; ''};
            app.DistillateFlowRateLabel = uilabel(app.Panel);
            app.DistillateFlowRateLabel.Text = 'Distillate Flow Rate:';
            app.BottomsFlowRateLabel = uilabel(app.Panel);
            app.BottomsFlowRateLabel.Text = 'Bottoms Flow Rate:';
            app.DoyouwanttoincludethevaporandliquidstreamsLabel = uilabel(app.Panel);
            app.DoyouwanttoincludethevaporandliquidstreamsLabel.Text = {'Do you want to include the vapor and liquid streams'; ''};
            app.abovethefeedtrayLabel = uilabel(app.Panel);
            app.abovethefeedtrayLabel.Text = 'above the feed tray?';
            app.SwitchLabel = uilabel(app.Panel);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.Panel, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.VaporFlowRateAboveFeedLabel = uilabel(app.Panel);
            app.VaporFlowRateAboveFeedLabel.Text = 'Vapor Flow Rate Above Feed:';
            app.LiquidFlowRateAboveFeedLabel = uilabel(app.Panel);
            app.LiquidFlowRateAboveFeedLabel.Text = 'Liquid Flow Rate Above Feed:';
            app.AssumesMcCabeThieleconditionsandLabel = uilabel(app.Panel);
            app.AssumesMcCabeThieleconditionsandLabel.Text = '* Assumes McCabe Thiele conditions and ';
            app.idealconditionsLabel = uilabel(app.Panel);
            app.idealconditionsLabel.Text = 'ideal conditions';
            app.GeneralColumnCharacteristicsLabel = uilabel(app.Panel);
            app.GeneralColumnCharacteristicsLabel.Text = 'General Column Characteristics:';
            app.ColumnBubbleTLabel = uilabel(app.Panel);
            app.ColumnBubbleTLabel.Text = 'Column Bubble T:';
            app.ColumnDewTLabel = uilabel(app.Panel);
            app.ColumnDewTLabel.Text = 'Column Dew T:';
            app.DFlowLabel = uilabel(app.Panel);
            app.DFlowLabel.Text = {'0'; ''};
            app.WFlowLabel = uilabel(app.Panel);
            app.WFlowLabel.Text = '0';
            app.VAboveLabel = uilabel(app.Panel);
            app.VAboveLabel.Text = '0';
            app.LAboveLabel2 = uilabel(app.Panel);
            app.LAboveLabel2.Text = {'0'; ''};
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Feed Tray Drawing + Description')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DistillationColumnApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end